classdef compare_exp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        z_error        matlab.ui.control.NumericEditField
        Label_7        matlab.ui.control.Label
        xy_error       matlab.ui.control.NumericEditField
        Label_6        matlab.ui.control.Label
        truth_path     matlab.ui.control.EditField
        Label_5        matlab.ui.control.Label
        dis__end       matlab.ui.control.NumericEditField
        Label_4        matlab.ui.control.Label
        dis__interval  matlab.ui.control.NumericEditField
        Label_3        matlab.ui.control.Label
        dis__start     matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        ComparethepredicitonresultwiththereferenceresultLabel  matlab.ui.control.Label
        result_path    matlab.ui.control.EditField
        Label          matlab.ui.control.Label
        RunButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
clc;close all;
addpath ./function/
positive_num = 0;
omitted_num = 0;
sum_truth_num = 0;
sum_exp_num = 0;
path = app.result_path.Value;
XY_EEROR = app.xy_error.Value;
Z_EEROR = app.z_error.Value;
pre_result_pack = [];
truth_result_pack = [];
path = string(path)+"\";
dis_start = app.dis__start.Value;
dis_interval = app.dis__interval.Value;
dis_end = app.dis__end.Value;
true_path = app.truth_path.Value;
for aa = 1:4
    for bb = 1:4
        final_pre_result = Exp_result(aa,bb,path,dis_start,dis_interval,dis_end);
        add_pre = ones(size(final_pre_result,1),2);
        add_pre(:,1)=add_pre(:,1)*aa;
        add_pre(:,2)=add_pre(:,2)*bb;
        new_pre_result = [final_pre_result,add_pre];
        pre_result_pack = cat(1,pre_result_pack,new_pre_result);
        load_path = sprintf("/a%db%d.mat",aa,bb);
        load_path = true_path + load_path;
        if ~exist(load_path)
            continue;
        end
        load(load_path);
        exp_num = size(final_pre_result,1);
        truth_num = size(output.depth,1);
        sum_truth_num = sum_truth_num + truth_num;
        sum_exp_num = sum_exp_num + exp_num;
        if exp_num == 0
            omitted_num = omitted_num + truth_num;
            continue;
        end
        for i = 1:exp_num
            X = final_pre_result(i,1);
            Y = final_pre_result(i,2);
            Z = final_pre_result(i,3);
            for j = 1:truth_num
                X0 = output.lateral_position{j}(1);
                Y0 = output.lateral_position{j}(2);
                Z0 = output.depth{j};
                if (abs(X-X0)<XY_EEROR) && (abs(Y-Y0)<XY_EEROR) && (abs(Z-Z0)<Z_EEROR) %原为10,10,151
                    positive_num = positive_num + 1;
                    break
                end
            end
        end
        for i = 1:truth_num
             X0 = output.lateral_position{i}(1);
             Y0 = output.lateral_position{i}(2);
             Z0 = output.depth{i};
             D0 = round(output.diameter{i});
             add_truth = ones(1,2);
             add_truth(:,1)=add_truth(:,1)*aa;
             add_truth(:,2)=add_truth(:,2)*bb;
             truth_result = [X0,Y0,Z0,D0,add_truth];
             truth_result_pack = cat(1,truth_result_pack,truth_result);
             for j = 1:exp_num
                 X = final_pre_result(j,1);
                 Y = final_pre_result(j,2);
                 Z = final_pre_result(j,3);
                 if (abs(X-X0)<XY_EEROR) && (abs(Y-Y0)<XY_EEROR) && (abs(Z-Z0)<Z_EEROR)
                     break;
                 end
                 if j == exp_num
                     omitted_num = omitted_num + 1 ;

                 end
             end
        end   
    end
end
negative_num = sum_exp_num - positive_num;
% omitted_num = truth_num - positive_num;
assignin("base",'pre_result_pack',pre_result_pack);
assignin("base",'truth_result_pack',truth_result_pack);
positive_num
negative_num
omitted_num
sum_exp_num
sum_truth_num
disp("extraction rate is");disp((sum_truth_num-omitted_num)/sum_truth_num)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Path of the prediction';
            app.result_path = uieditfield(app.UIFigure, 'text');
            app.result_path.Value = '../data/experiment_data/exp_pre';
            app.ComparethepredicitonresultwiththereferenceresultLabel = uilabel(app.UIFigure);
            app.ComparethepredicitonresultwiththereferenceresultLabel.Text = 'Compare the prediciton result with the reference result';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Start distance';
            app.dis__start = uieditfield(app.UIFigure, 'numeric');
            app.dis__start.Value = 1800;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Axial interval';
            app.dis__interval = uieditfield(app.UIFigure, 'numeric');
            app.dis__interval.Value = 50;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'End distance';
            app.dis__end = uieditfield(app.UIFigure, 'numeric');
            app.dis__end.Value = 4800;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Path of the reference';
            app.truth_path = uieditfield(app.UIFigure, 'text');
            app.truth_path.Value = '../data/experiment_data/reference_result';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Error in the lateral direction';
            app.xy_error = uieditfield(app.UIFigure, 'numeric');
            app.xy_error.Value = 10;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Error in the axial direction';
            app.z_error = uieditfield(app.UIFigure, 'numeric');
            app.z_error.Value = 151;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compare_exp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end