classdef compare_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ComparisoninsimulationresultLabel  matlab.ui.control.Label
        truth_path     matlab.ui.control.EditField
        Label_5        matlab.ui.control.Label
        dis__end       matlab.ui.control.NumericEditField
        Label_4        matlab.ui.control.Label
        dis__interval  matlab.ui.control.NumericEditField
        Label_3        matlab.ui.control.Label
        dis__start     matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        result_path    matlab.ui.control.EditField
        Label          matlab.ui.control.Label
        RunButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
clc;close all;
addpath ./function/
positive_num = 0;
omitted_num = 0;
sum_truth_num = 0;
sum_exp_num = 0;
added_diameter = 0;
path = app.result_path.Value;
path = string(path)+"\";
dis_start = app.dis__start.Value;
dis_interval = app.dis__interval.Value;
dis_end = app.dis__end.Value;
true_path = app.truth_path.Value;
true_path = string(true_path)+"\";
pre_result_pack = [];
truth_result_pack = [];
FP_pack = [];
FN_pack = [];
for group_i = 1:9
        pre_result = Sim_result('pr',group_i,path,dis_start,dis_interval,dis_end,added_diameter);
        truth_result = Sim_result('truth',group_i,true_path,dis_start,dis_interval,dis_end,added_diameter);
        add_pre = group_i*ones(size(pre_result,1),1);
        new_pre_result = [pre_result, add_pre];
        add_truth = group_i*ones(size(truth_result,1),1);
        new_truth_result = [truth_result, add_truth];
        pre_result_pack = cat(1,pre_result_pack,new_pre_result);
        truth_result_pack = cat(1,truth_result_pack,new_truth_result);
        pre_num = size(pre_result,1);
        truth_num = size(truth_result,1);
        sum_truth_num = sum_truth_num + truth_num;
        sum_exp_num = sum_exp_num + pre_num;
        if pre_num == 0
            omitted_num = omitted_num + truth_num;
            continue;
        end
        for i = 1:pre_num
            X = pre_result(i,1);
            Y = pre_result(i,2);
            Z = pre_result(i,3);
            diameter = pre_result(i,4);
            for j = 1:truth_num
                X0 = truth_result(j,1);
                Y0 = truth_result(j,2);
                Z0 = truth_result(j,3);
                if (abs(X-X0)<10) && (abs(Y-Y0)<10) && (abs(Z-Z0)<30)
                    positive_num = positive_num + 1;
                    break;
                elseif j==truth_num
                    FP_result = [X,Y,Z,diameter,group_i];
                    FP_pack = cat(1,FP_pack,FP_result);
                end
            end
        end
        for i = 1:truth_num
             X0 = truth_result(i,1);
             Y0 = truth_result(i,2);
             Z0 = truth_result(i,3);
             diameter = truth_result(i,4);
             for j = 1:pre_num
                 X = pre_result(j,1);
                 Y = pre_result(j,2);
                 Z = pre_result(j,3);
                 if (abs(X-X0)<10) && (abs(Y-Y0)<10) && (abs(Z-Z0)<30) 
                     break;
                 end
                 if j == pre_num
                     omitted_num = omitted_num + 1 ;
                     FN_result = [X0,Y0,Z0,diameter,group_i];
                     FN_pack = cat(1,FN_pack,FN_result);
                 end
             end
        end   
end
assignin("base",'pre_result_pack',pre_result_pack);
assignin("base",'truth_result_pack',truth_result_pack);
assignin("base","FN_pack",FN_pack);
assignin("base","FP_pack",FP_pack);
negative_num = sum_exp_num - positive_num;
positive_num
negative_num
omitted_num
sum_exp_num
sum_truth_num
disp("extraction rate is");disp((sum_truth_num-omitted_num)/sum_truth_num)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'test result path';
            app.result_path = uieditfield(app.UIFigure, 'text');
            app.result_path.Value = '../data/simulation_data/concentration_limit/test_out';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Start distance';
            app.dis__start = uieditfield(app.UIFigure, 'numeric');
            app.dis__start.Value = 1800;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Axial interval';
            app.dis__interval = uieditfield(app.UIFigure, 'numeric');
            app.dis__interval.Value = 30;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'End distance';
            app.dis__end = uieditfield(app.UIFigure, 'numeric');
            app.dis__end.Value = 3660;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'ground truth path';
            app.truth_path = uieditfield(app.UIFigure, 'text');
            app.truth_path.Value = '../data/simulation_data/concentration_limit/test_truth';
            app.ComparisoninsimulationresultLabel = uilabel(app.UIFigure);
            app.ComparisoninsimulationresultLabel.Text = 'Comparison in simulation result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compare_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end