classdef generate_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        gama                 matlab.ui.control.NumericEditField
        Label_12             matlab.ui.control.Label
        noise_fangcha        matlab.ui.control.NumericEditField
        Label_11             matlab.ui.control.Label
        noise_junzhi         matlab.ui.control.NumericEditField
        Label_10             matlab.ui.control.Label
        DatamigrationButton  matlab.ui.control.Button
        group_num            matlab.ui.control.NumericEditField
        Label_9              matlab.ui.control.Label
        CreatefolderButton   matlab.ui.control.Button
        DeleteButton         matlab.ui.control.Button
        GeneratethesimulationdatafortrainingtheUNetLabel  matlab.ui.control.Label
        max_diameter         matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        particle_num         matlab.ui.control.NumericEditField
        Label_6              matlab.ui.control.Label
        min_diameter         matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        end_dis              matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        start_dis            matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        z_interval           matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        holo_num             matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        RunButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
close all; clc;
addpath .\function\
%% The HCS method to generate the simulated hologram and the ground truth
for serinum=1:app.holo_num.Value % group number
    % parameter initialization
    um=1e-6;
    nm=1e-9;
    lambda=660*nm;
    pixel_pitch=2.2*um;

    % set the axial distance
    dis_interval=app.z_interval.Value * um;
    dis_start=app.start_dis.Value * um;
    dis_end=app.end_dis.Value * um;
    dis_opslice=dis_start:dis_interval:dis_end;  
    slice_num=length(dis_opslice);

    % object generation, the size choices of the particles.
    par_size_mode=app.min_diameter.Value:1:app.max_diameter.Value; % diameter 
    size_slice=1;
    for nm_i=par_size_mode 
        sz_nmi=nm_i;
        name_nmi=sprintf('../data/experiment data/binary_model/%gum.mat', sz_nmi); % our binary model
        load(name_nmi) 
        im_nmi_sz = data_plane;
        im_nmi=im_nmi_sz(128-20:128+19,128-20:128+19); % only the 40*40 pixels in the middle are kept
        particle_model(:,:,size_slice)=im_nmi; 
        size_slice=size_slice+1;                       % next model
    end

    % put all the particles into each simulated layer image
    parnum=app.particle_num.Value;  % particle number
    size_mode=0;                    % randomly select the particle diameter
    volume_singlep=zeros(256,256,parnum);
    my_prediction = zeros(256,256,parnum);
    dis_singlep=0;
    particle_mode_num=length(par_size_mode); 
    if size_mode==0 
        for numi=1:parnum % one particle by one particle
            size_mode_temp=randperm(particle_mode_num, 1); 
            particle_ori_set=particle_model(:,:,size_mode_temp);

            % set the position at the xy plane
            x_centre=randi([20,236],1); % x-direction centre position ranges from [20,236]
            y_centre=randi([20,236],1); % y-direction centre position ranges from [20,236]
            particle_single_temp=zeros(256,256);
            particle_single_temp(y_centre-19:y_centre+20,x_centre-19:x_centre+20)=particle_ori_set;
            
            % choose a random distance
            my_gray=zeros(256,256);  
            my_gray(y_centre-1:y_centre+1,x_centre-1:x_centre+1)=par_size_mode(size_mode_temp)*4;
            dis_singlep(numi)=dis_opslice(randperm(length(dis_opslice),1));% take a random Z distance from the Z distance sequence          
            my_prediction(:,:,numi)=my_gray;
            volume_singlep(:,:,numi)=particle_single_temp; % save the layer image after filling the particle model
        end
    end
    my_pred = my_prediction;
    my_final = zeros(256,256,slice_num);
    object_singlep=volume_singlep; 

    % save the ground truth images according to the original particle field volume 
    slice_obj_singlep=sort(unique(dis_singlep));          % all the Z distance with particles inside
    slice_volume_dis=length(slice_obj_singlep);     
    obj_volune_dis=zeros(256,256,slice_volume_dis); 
    temp_nimi=0;
    for numi=1:slice_num 
        if ~ismember(dis_opslice(numi),slice_obj_singlep) % when there is no particle in some planes
            empety=zeros(256,256); 
            obj_volune_dis(:,:,numi)=empety;              % assign this plane directly to the empty plane
             savename_prediction=sprintf('../data/simulation_data/generation_data/prediction/pr%g_%gNum_%gSlice_at%gum.bmp',...
                serinum, parnum,slice_num,dis_opslice(numi)*1e6); % all the label images are put into the prediction folder
            % group, the number of particles in this group, the number of planes in this group, the Z distance in this plane
            imwrite(empety, savename_prediction,'bmp')
            my_final(:,:,numi)=empety;
            continue;
        else
            temp_nimi=temp_nimi+1;
            index3=find(dis_singlep==slice_obj_singlep(temp_nimi)); 
            temp_single_slice=sum(object_singlep(:,:,index3),3); % all the particle models are added to get the whole layer image
            my_single_slice=sum(my_pred(:,:,index3),3);            
            obj_volune_dis(:,:,numi)=temp_single_slice;         
            savename_prediction=sprintf('../data/simulation_data/generation_data/prediction/pr%g_%gNum_%gSlice_at%gum.bmp',...
                serinum, parnum,slice_num,dis_opslice(numi)*1e6);
            my_single_slice = uint8(my_single_slice);
            imwrite(my_single_slice, savename_prediction,'bmp') 
            my_final(:,:,numi) = my_single_slice;
        end
    end

    % hologram generation
    junzhi = app.noise_junzhi.Value;
    fangcha = app.noise_fangcha.Value;
    gaama = app.gama.Value;
    for numi=1:parnum  
        % AS diffraction to the CMOS，Input：diffraction distance，object plane，pixel pitch，wavelength；Output：complex hologram
        [singlehlo,~]=Backpropagation((dis_singlep(numi)),object_singlep(:,:,numi),pixel_pitch,lambda);
        if numi==1
            ysum=singlehlo;  
        else
            ysum=singlehlo+ysum; % complex value hologram superposition
        end
    end
    hlo0=abs(1+ysum).^2-1;       % DC-subtraction

    %% The OCE method, Holo-processing
    % add Gaussian random noise to the raw hologram
    zuida = max(hlo0(:));  zuixiao = min(hlo0(:));
    leng = zuida - zuixiao;
    sigma = std2(hlo0);
    noise = normrnd(-junzhi*leng,fangcha*sigma,[256,256]);
    hlo = hlo0 + noise;
    hlo1=im2uint8(hlo); % 0-255
    new_savename = sprintf('../data/simulation_data/generation_data/new_hologram/hlo%g_%g_num_%g_slice.bmp',...
        serinum, parnum,slice_num); 
    imwrite(hlo1,new_savename,"bmp") % the hologram is quantized to 0-255 for visualization, but the data for reconstruction is not

    % backpropagation: hologram reconstruction from the hologram with noise
    redis=dis_opslice; 
    redis_num=length(redis); 
    for numi=1:redis_num % one reconstruction plane by one reconstruction plane
        [re_single,~]=Backpropagation(-1*redis(numi),hlo,pixel_pitch,lambda); 
        savename=sprintf('../data/simulation_data/generation_data/reconstruction/re%g_%gNum_%gSlice_at%gum.bmp',...
            serinum, parnum,slice_num,redis(numi)*1e6); 
        re_temp = mat2gray(abs(re_single));
        re_temp = 255*re_temp;
        re_temp=uint8(re_temp);
        
        % Gamma transformation to all the raw reconstructed images
        re_temp = imadjust(re_temp,[0 1],[0 1],gaama);
        imwrite(re_temp, savename,'bmp')
    end
end
disp("Running is over")
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete ../data/simulation_data/generation_data/new_hologram/*.bmp
            delete ../data/simulation_data/generation_data/prediction/*.bmp
            delete ../data/simulation_data/generation_data/reconstruction/*.bmp
            disp("Deletion is completed")
        end

        % Button pushed function: CreatefolderButton
        function CreatefolderButtonPushed(app, event)
            new_path = sprintf("../data/simulation_data/group%d",app.group_num.Value);
            mkdir(new_path + "/train_in")
            mkdir(new_path + "/train_out")
            mkdir(new_path + "/exp_pre")
            disp("Folder is created")
        end

        % Button pushed function: DatamigrationButton
        function DatamigrationButtonPushed(app, event)
            new_path = sprintf("../data/simulation_data/group%d",app.group_num.Value);
            in_path = new_path + "/train_in/";
            out_path = new_path + "/train_out/";
            copyfile("../data/simulation_data/generation_data/reconstruction/",in_path)
            copyfile("../data/simulation_data/generation_data/prediction/",out_path)
            disp("Migration is completed")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Group number';
            app.holo_num = uieditfield(app.UIFigure, 'numeric');
            app.holo_num.Value = 3;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Axial interval';
            app.z_interval = uieditfield(app.UIFigure, 'numeric');
            app.z_interval.Value = 200;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Start distance';
            app.start_dis = uieditfield(app.UIFigure, 'numeric');
            app.start_dis.Value = 1800;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'End distance';
            app.end_dis = uieditfield(app.UIFigure, 'numeric');
            app.end_dis.Value = 4800;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Minimum diameter';
            app.min_diameter = uieditfield(app.UIFigure, 'numeric');
            app.min_diameter.Value = 20;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Particle number';
            app.particle_num = uieditfield(app.UIFigure, 'numeric');
            app.particle_num.Value = 12;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Maximum diameter';
            app.max_diameter = uieditfield(app.UIFigure, 'numeric');
            app.max_diameter.Value = 30;
            app.GeneratethesimulationdatafortrainingtheUNetLabel = uilabel(app.UIFigure);
            app.GeneratethesimulationdatafortrainingtheUNetLabel.Text = 'Generate the simulation data for training the U-Net';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CreatefolderButton = uibutton(app.UIFigure, 'push');
            app.CreatefolderButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefolderButtonPushed, true);
            app.CreatefolderButton.Text = 'Create folder';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Group index';
            app.group_num = uieditfield(app.UIFigure, 'numeric');
            app.group_num.Value = 1;
            app.DatamigrationButton = uibutton(app.UIFigure, 'push');
            app.DatamigrationButton.ButtonPushedFcn = createCallbackFcn(app, @DatamigrationButtonPushed, true);
            app.DatamigrationButton.Text = 'Data migration';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'N_R';
            app.noise_junzhi = uieditfield(app.UIFigure, 'numeric');
            app.noise_junzhi.Value = 0.3068;
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'N_sigma';
            app.noise_fangcha = uieditfield(app.UIFigure, 'numeric');
            app.noise_fangcha.Value = 0.2164;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'Gamma factor';
            app.gama = uieditfield(app.UIFigure, 'numeric');
            app.gama.Value = 0.8337;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generate_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end